classdef FeatureExtraction < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        UIAxes                       matlab.ui.control.UIAxes
        UIAxes2                      matlab.ui.control.UIAxes
        TILESINSPECTIONLabel         matlab.ui.control.Label
        FeaturesExtractionPanel      matlab.ui.container.Panel
        BrowseButton                 matlab.ui.control.Button
        UITable                      matlab.ui.control.Table
        GLCMButton                   matlab.ui.control.Button
        ResetButton                  matlab.ui.control.Button
        ExitButton                   matlab.ui.control.Button
        CameraPanel                  matlab.ui.container.Panel
        ConfigureCamButton           matlab.ui.control.Button
        CaptureButton                matlab.ui.control.Button
        UIAxes3                      matlab.ui.control.UIAxes
        CloseCamButton               matlab.ui.control.Button
        PredictionofClassPanel       matlab.ui.container.Panel
        PredictButton                matlab.ui.control.Button
        SelectModelTrained           matlab.ui.control.DropDown
        SelectModelTrainedDropDownLabel  matlab.ui.control.Label
        QualityResultEditFieldLabel  matlab.ui.control.Label
        QualityResultEditField       matlab.ui.control.EditField
        ActualEditFieldLabel         matlab.ui.control.Label
        ActualEditField              matlab.ui.control.EditField
    end

    
    properties (Access = public)
        Img; output 
        VidObj;
        value;
        data2;
        D;
        i;
    end
    
    methods (Access = public)
        
         function [p, h] = predictNN(X, varargin)
            %PREDICTNN Predict the label of an input given a trained neural network
            %
            %   [p, h] = PREDICTNN(X, Theta, activationFn) outputs the predicted 
            %   label of X given the trained weights of a neural network Theta. This
            %   option does not normalize the data.
            %   or 
            %   [p, h] = PREDICTNN(X, modelNN)
            %   This normalizes the data based on the mu and std in the modelNN
            %
            %   Vahe Tshitoyan
            %   26/08/2017
            %
            %   Inputs
            %   X:              The feature matrix to run the prediction on
            %   Theta:          The weights as a cell array modelNN.Theta
            %   activationFn:   'tanh' or 'sigm'
            %   modelNN:        The trained NN model
            %
            %   Outputs:
            %   p:              The predicted label
            %   h:              The values of the hypotheses for all labels as a vector
            
            if nargin==2
                modelNN = varargin{1};
                Theta = modelNN.Theta;
                activationFn = modelNN.activationFn;
                % also need to normalize here
                X = (X - repmat(modelNN.nnMu,size(X,1),1))...
                    ./repmat(modelNN.nnSigma,size(X,1),1);
                X(isnan(X)) = 0;
            elseif nargin==3
                Theta = varargin{1};
                activationFn = varargin{2};
            else
                error('predictNN: Invalid Number of Arguments');
            end
            
            % choose the activation function
            if strcmp(activationFn, 'tanh')
                aF = @tanh;
            else % the default is sigmoid for now
                aF = @sigmoid;
            end
            
            % Number of examples
            m = size(X, 1);
            
            % hypothesis values of 1st layer
            h = aF([ones(m, 1) X] * Theta{1}');
            % hypothesis values of the consecutive layers
            for ii=2:numel(Theta)
                h = aF([ones(m, 1) h] * Theta{ii}');
            end
            
            % prediction is the highest probability value
            [~, p] = max(h, [], 2);
         end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
          [filename, pathname] = uigetfile('*.*');
          fich1 = fullfile(pathname,filename)
          disp(fich1)
          whos fich1
        
          %if char(filename,'*.png')
              app.D=imread(fich1);
              imshow(app.D,'Parent', app.UIAxes);
                app.UIAxes.XLim = [0,720];
                app.UIAxes.YLim = [0,720];
                app.UIAxes.XTick = [];
                app.UIAxes.YTick = [];
                pbaspect(app.UIAxes,[720,720,1])
              app.Img=rgb2gray(app.D);
              imshow(app.Img, 'Parent', app.UIAxes2);
                app.UIAxes2.XLim = [0,720];
                app.UIAxes2.YLim = [0,720];
                app.UIAxes2.XTick = [];
                app.UIAxes2.YTick = [];
                pbaspect(app.UIAxes2,[720,720,1])
              set(app.GLCMButton,'Enable','on');
              set(app.UITable, 'Data', []);
          
%           %elseif char(filename,'*.mp4')
%               app.VidObj= VideoReader(filename);
%              
%               while hasFrame(app.VidObj)
%                   vidFrame = readFrame(app.VidObj);
%                   imshow(vidFrame, 'Parent', app.UIAxes3);
%               end
%              
%           %end
        end

        % Button pushed function: GLCMButton
        function GLCMButtonPushed(app, event)
           
           global Img 
           Img = app.Img;
           offsets1 = [0 1; -1 1; -1 0; -1 -1];
           %GLCM = graycomatrix(Img, 'Offset',offsets1);
           %pixel_dist = num2str(app.PixelDistanceEditField.Value);
          
           %offsets2 = [0 pixel_dist; -pixel_dist pixel_dist; -pixel_dist 0; -pixel_dist -pixel_dist];
           GLCM = graycomatrix(Img,'Offset', offsets1);
           stats = graycoprops(GLCM,{'Contrast','Correlation','Energy','Homogeneity'});
            
           Contrast = stats.Contrast;
           Correlation = stats.Correlation;
           Energy = stats.Energy;
           Homogeneity = stats.Homogeneity;
           
            data = get(app.UITable,'Data');
            data{1,1} = num2str(Contrast(1));
            data{1,2} = num2str(Contrast(2));
            data{1,3} = num2str(Contrast(3));
            data{1,4} = num2str(Contrast(4));
            %data{1,5} = num2str(mean(Contrast));
            
            data{2,1} = num2str(Correlation(1));
            data{2,2} = num2str(Correlation(2));
            data{2,3} = num2str(Correlation(3));
            data{2,4} = num2str(Correlation(4));
            %data{2,5} = num2str(mean(Correlation));
            
            data{3,1} = num2str(Energy(1));
            data{3,2} = num2str(Energy(2));
            data{3,3} = num2str(Energy(3));
            data{3,4} = num2str(Energy(4));
            %data{3,5} = num2str(mean(Energy));
            
            data{4,1} = num2str(Homogeneity(1));
            data{4,2} = num2str(Homogeneity(2));
            data{4,3} = num2str(Homogeneity(3));
            data{4,4} = num2str(Homogeneity(4));
            %data{4,5} = num2str(mean(Homogeneity));
            
            app.data2 = get(app.UITable,'Data');
            app.data2(1,1) = (Contrast(1));
            app.data2(1,2) = (Contrast(2));
            app.data2(1,3) = (Contrast(3));
            app.data2(1,4) = (Contrast(4));
           
            app.data2(1,5) = (Correlation(1));
            app.data2(1,6) = (Correlation(2));
            app.data2(1,7) = (Correlation(3));
            app.data2(1,8) = (Correlation(4));
            
            app.data2(1,9) = (Energy(1));
            app.data2(1,10) = (Energy(2));
            app.data2(1,11) = (Energy(3));
            app.data2(1,12) = (Energy(4));
            
            app.data2(1,13) = (Homogeneity(1));
            app.data2(1,14) = (Homogeneity(2));
            app.data2(1,15) = (Homogeneity(3));
            app.data2(1,16) = (Homogeneity(4));
            
            disp(app.data2)    
            set(app.UITable,'Data',data,'ForegroundColor',[0 0 0]);
           
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            cla(app.UIAxes, 'reset');
            title(app.UIAxes, 'Input')
            app.UIAxes.PlotBoxAspectRatio = [1 0.551724137931034 0.551724137931034];
            app.UIAxes.Layer = 'top';
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.Color = [0 0 0];
            app.UIAxes.Position = [11 260 300 185];
            
            cla(app.UIAxes2, 'reset');
            title(app.UIAxes2, 'Grayscale')
            app.UIAxes2.PlotBoxAspectRatio = [1 0.551724137931034 0.551724137931034];
            app.UIAxes2.MinorGridColor = [0.502 0.502 0.502];
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UIAxes2.Color = [0 0 0];
            app.UIAxes2.Position = [13 55 300 185];
            
            set(app.GLCMButton, 'enable', 'off');
            
            app.UITable.Data = {};
            app.UITable = uitable(app.FeaturesExtractionPanel);
            app.UITable.ColumnName = {'0'; '45'; '90'; '135'};
            app.UITable.RowName = {'Contrast'; 'Correlation'; 'Energy'; 'Homogeneity'};
            app.UITable.ColumnEditable = false;
            app.UITable.Position = [9 5 481 99];
            
            app.SelectModelTrained.Value = '-';
            set(app.PredictButton, 'enable', 'off');
            app.QualityResultEditField.Value = '';
            
            
        end

        % Button pushed function: ConfigureCamButton
        function ConfigureCamButtonPushed(app, event)
%             IAHI = imaqhwinfo;
%             IA = (IAHI.InstalledAdaptors);
%             E = menu('Select Video Input Device:',IA);
%             
%             if isempty(IA)||E==0
%                 msgbox({'You don''t have any VideoInput Installed Adaptors!',...
%                     'Please! try again and select Adaptor properly.'})
%                 return
%             end
%             
%             IA = char(IA);
%             IA = IA(E,:);
%             IA(IA==' ')=[];
%             x = imaqhwinfo(IA);
%             
%             try
%                 DeviceID = menu('Select Device ID',x.DeviceIDs);
%                 F = x.DeviceInfo(DeviceID).SupportedFormats;
%                 nF = menu('Select FORMAT',F);
%                 Format = F{nF};
%             catch
%                 warndlg({'Try Another Device or ID ';...
%                     'You Don''t Have Installed This Device (VideoInputDevice)'})
%                 return
%             end
            
%             app.VidObj = videoinput(IA, DeviceID, Format);
%             %app.VidObj = app.VidObj;
%             vidRes = get(app.VidObj, 'VideoResolution');
%             nBands = get(app.VidObj, 'NumberOfBands');
%             hImage = image( zeros(vidRes(2), vidRes(1), nBands));
%             preview(app.VidObj, hImage)
%                         
%             set(app.VidObj,'returnedcolorspace','rgb');
%             %guidata(hObject, handles);
            
            % Image acquisition
            %app.VidObj = videoinput(IA, DeviceID, Format);
            %if videoinput('winvideo','2')
                app.VidObj = videoinput('winvideo','2');
                % Create blank image
                hImage = image(app.UIAxes3,zeros(720,1280,3)); %If image resolution is 1280x720
                pause(2)
                % These lines set proper aspect ratio
                app.UIAxes3.XLim = [0,1280];
                app.UIAxes3.YLim = [0,720];
                app.UIAxes3.XTick = [];
                app.UIAxes3.YTick = [];
                pbaspect(app.UIAxes3,[1280,720,1])
                % Preview the image
                preview(app.VidObj,hImage)
                
%             elseif VideoReader(app.fich1)
%                 app.VidObj = VideoReader(app.fich1);
%                 hImage = image(app.UIAxes3,zeros(720,1280,3)); %If image resolution is 1280x720
%                 pause(2)
%                 % These lines set proper aspect ratio
%                 app.UIAxes3.XLim = [0,1280];
%                 app.UIAxes3.YLim = [0,720];
%                 app.UIAxes3.XTick = [];
%                 app.UIAxes3.YTick = [];
%                 pbaspect(app.UIAxes3,[1280,720,1])
%                 % Preview the image
%                 preview(app.VidObj,hImage)
%             end
          
            set(app.VidObj,'returnedcolorspace','rgb');
            set(app.CaptureButton,'Enable','on');
        end

        % Button pushed function: CaptureButton
        function CaptureButtonPushed(app, event)
            try
                app.VidObj = app.VidObj;
                app.i = getsnapshot(app.VidObj);
               
               
%                 n = str2double(app.Edit,'String')+1;
%                 set(app.Edit,'String',num2str(n));
%                 
%                 imwrite(i, strcat(['Image ',num2str(n),'.png']));
                imshow(app.i, 'Parent', app.UIAxes);
                  app.UIAxes.XLim = [0,720];
                  app.UIAxes.YLim = [0,720];
                  app.UIAxes.XTick = [];
                  app.UIAxes.YTick = [];
                  pbaspect(app.UIAxes,[720,720,1])
                app.Img = rgb2gray(app.i);
                imshow(app.Img, 'Parent', app.UIAxes2);
                  app.UIAxes2.XLim = [0,720];
                  app.UIAxes2.YLim = [0,720];
                  app.UIAxes2.XTick = [];
                  app.UIAxes2.YTick = [];
                  pbaspect(app.UIAxes2,[720,720,1])
            catch
                
            end
            set(app.GLCMButton,'Enable','on');
            set(app.UITable, 'Data', []);  

        end

        % Button pushed function: CloseCamButton
        function CloseCamButtonPushed(app, event)
            closepreview(app.VidObj);
            set(app.CaptureButton,'Enable','off');
            cla(app.UIAxes3, 'reset');
            app.UIAxes3.PlotBoxAspectRatio = [1 0.551724137931034 0.551724137931034];
            app.UIAxes3.Layer = 'top';
            app.UIAxes3.XTick = [];
            app.UIAxes3.YTick = [];
            app.UIAxes3.Color = [0 0 0];
            app.UIAxes3.Position = [2 86 300 185];
            
        end

        % Value changed function: SelectModelTrained
        function SelectModelTrainedValueChanged(app, event)
            app.value = app.SelectModelTrained.Value;
            set(app.PredictButton, 'Enable', 'on');
        end

        % Button pushed function: PredictButton
        function PredictButtonPushed(app, event)
            app.value = app.SelectModelTrained.Value;
            
            if strcmpi(app.value,'-')
                app.QualityResultEditField.Value='-';
            elseif strcmpi(app.value,'Backpro NN')
                addpath('E:\STUDY\Semester 7\PA\SimpleNN from Github vtshitoyan_2 - 8 Channel\')
                %load('E:\STUDY\Semester 7\PA\SimpleNN from Github vtshitoyan_2 - 8 Channel\tilestrainingNN.m')
                load('E:\STUDY\Semester 7\PA\SimpleNN from Github vtshitoyan_2 - 8 Channel\result\Kerent\NN85.mat')

                %rI = randi(size(testData.X, 1)); % a random index
    
                p = predictNN(app.data2, modelNN); % the prediction
                p = p-1;
%                 switch testData.y(rI)
%                     case 0
%                         app.ActualEditField.Value='Defect';
%                     case 1
%                         app.ActualEditField.Value='Normal';
%                 end
                
                switch p
                    case 0
                        app.QualityResultEditField.Value='Normal';
                    case 1
                        app.QualityResultEditField.Value='Defect';
                end
                %app.QualityResultEditField.Value='BackPro';
                
            elseif strcmpi(app.value,'Pattern NN')
                %0=Normal
                %1=Defect
                
                %1=Kelasnya
                %2=Bukan kelasnya
                
                load('E:\STUDY\Semester 7\PA\Program\PatternNN\PatternNN84.mat')
                y=net(app.data2')
                e=gsubtract(t,y)
                tind=vec2ind(t)
                yind=vec2ind(y)
                
                switch yind
                    case 2
                        app.QualityResultEditField.Value='Normal';
                    case 1
                        app.QualityResultEditField.Value='Defect';
                end
                
                %app.QualityResultEditField.Value='Pattern';
            elseif strcmpi(app.value,'Prob NN')
                app.QualityResultEditField.Value='Prob';
            else 
                load('E:\STUDY\Semester 7\PA\Program\ImageCC\ImageCCtrained81.mat')
                
                if app.D
                [labelIdx, score] = predict(categoryClassifier, app.D);
                else
                [labelIdx, score] = predict(categoryClassifier, app.i);
                end
                Result = categoryClassifier.Labels(labelIdx);
                
                if strcmpi(Result, 'Defect') 
                    app.QualityResultEditField.Value='Defect';
                elseif strcmpi(Result, 'Normal')
                    app.QualityResultEditField.Value='Normal';
                end
                %app.QualityResultEditField.Value='ImgCC';      
            end
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            closereq();
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Input')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Grayscale')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.TILESINSPECTIONLabel = uilabel(app.UIFigure);
            app.TILESINSPECTIONLabel.Text = 'TILES INSPECTION';
            app.FeaturesExtractionPanel = uipanel(app.UIFigure);
            app.FeaturesExtractionPanel.Title = 'Features Extraction';
            app.BrowseButton = uibutton(app.FeaturesExtractionPanel, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.UITable = uitable(app.FeaturesExtractionPanel);
            app.UITable.ColumnName = {'0'; '45'; '90'; '135'};
            app.UITable.RowName = {'Contrast'; 'Correlation'; 'Energy'; 'Homogeneity'};
            app.UITable.ColumnEditable = false;
            app.GLCMButton = uibutton(app.FeaturesExtractionPanel, 'push');
            app.GLCMButton.ButtonPushedFcn = createCallbackFcn(app, @GLCMButtonPushed, true);
            app.GLCMButton.Enable = 'off';
            app.GLCMButton.Text = 'GLCM';
            app.ResetButton = uibutton(app.FeaturesExtractionPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.ExitButton = uibutton(app.FeaturesExtractionPanel, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.CameraPanel = uipanel(app.UIFigure);
            app.CameraPanel.Title = 'Camera';
            app.ConfigureCamButton = uibutton(app.CameraPanel, 'push');
            app.ConfigureCamButton.ButtonPushedFcn = createCallbackFcn(app, @ConfigureCamButtonPushed, true);
            app.ConfigureCamButton.Text = 'Configure Cam';
            app.CaptureButton = uibutton(app.CameraPanel, 'push');
            app.CaptureButton.ButtonPushedFcn = createCallbackFcn(app, @CaptureButtonPushed, true);
            app.CaptureButton.Enable = 'off';
            app.CaptureButton.Text = 'Capture';
            app.UIAxes3 = uiaxes(app.CameraPanel);
            title(app.UIAxes3, '')
            app.UIAxes3.XTick = [];
            app.UIAxes3.YTick = [];
            app.CloseCamButton = uibutton(app.CameraPanel, 'push');
            app.CloseCamButton.ButtonPushedFcn = createCallbackFcn(app, @CloseCamButtonPushed, true);
            app.CloseCamButton.Text = 'Close Cam';
            app.PredictionofClassPanel = uipanel(app.UIFigure);
            app.PredictionofClassPanel.Title = 'Prediction of Class';
            app.PredictButton = uibutton(app.PredictionofClassPanel, 'push');
            app.PredictButton.ButtonPushedFcn = createCallbackFcn(app, @PredictButtonPushed, true);
            app.PredictButton.Enable = 'off';
            app.PredictButton.Text = 'Predict';
            app.SelectModelTrained = uidropdown(app.PredictionofClassPanel);
            app.SelectModelTrained.Items = {'-', 'Backpro NN', 'Pattern NN', 'Prob NN', 'Img Category'};
            app.SelectModelTrained.ValueChangedFcn = createCallbackFcn(app, @SelectModelTrainedValueChanged, true);
            app.SelectModelTrained.Value = '-';
            app.SelectModelTrainedDropDownLabel = uilabel(app.PredictionofClassPanel);
            app.SelectModelTrainedDropDownLabel.Text = 'Select Model Trained';
            app.QualityResultEditFieldLabel = uilabel(app.PredictionofClassPanel);
            app.QualityResultEditFieldLabel.Text = 'Quality Result';
            app.QualityResultEditField = uieditfield(app.PredictionofClassPanel, 'text');
            app.ActualEditFieldLabel = uilabel(app.PredictionofClassPanel);
            app.ActualEditFieldLabel.Visible = 'off';
            app.ActualEditFieldLabel.Text = 'Actual';
            app.ActualEditField = uieditfield(app.PredictionofClassPanel, 'text');
            app.ActualEditField.Visible = 'off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = FeatureExtraction

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end